classdef VirtualRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Theta1EditFieldLabel      matlab.ui.control.Label
        Theta1EditField           matlab.ui.control.NumericEditField
        Theta2EditFieldLabel      matlab.ui.control.Label
        Theta2EditField           matlab.ui.control.NumericEditField
        Theta3EditFieldLabel      matlab.ui.control.Label
        Theta3EditField           matlab.ui.control.NumericEditField
        ForwardButton             matlab.ui.control.Button
        AzimuthSliderLabel        matlab.ui.control.Label
        AzimuthSlider             matlab.ui.control.Slider
        ElevationSliderLabel      matlab.ui.control.Label
        ElevationSlider           matlab.ui.control.Slider
        ShowCheckerboardCheckBox  matlab.ui.control.CheckBox
        ShowObjectsCheckBox       matlab.ui.control.CheckBox
        ShowCalibToolCheckBox     matlab.ui.control.CheckBox
        ShowGripperCheckBox       matlab.ui.control.CheckBox
        PixelXEditFieldLabel      matlab.ui.control.Label
        PixelXEditField           matlab.ui.control.NumericEditField
        PixelYEditFieldLabel      matlab.ui.control.Label
        PixelYEditField           matlab.ui.control.NumericEditField
        ViewListBoxLabel          matlab.ui.control.Label
        ViewListBox               matlab.ui.control.ListBox
        SnapCameraButton          matlab.ui.control.Button
        NotinworkspaceLabel       matlab.ui.control.Label
        NotinworkspaceLamp        matlab.ui.control.Lamp
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        InverseButton             matlab.ui.control.Button
        Theta1SliderLabel         matlab.ui.control.Label
        Theta1Slider              matlab.ui.control.Slider
        Theta2SliderLabel         matlab.ui.control.Label
        Theta2Slider              matlab.ui.control.Slider
        Theta3SliderLabel         matlab.ui.control.Label
        Theta3Slider              matlab.ui.control.Slider
        FWDTrajectoryButton       matlab.ui.control.Button
        InverseTrajectoryButton   matlab.ui.control.Button
        Theta1Minus10Button       matlab.ui.control.Button
        Theta1Minus1Button        matlab.ui.control.Button
        Theta1Add1Button          matlab.ui.control.Button
        Theta1Add10Button         matlab.ui.control.Button
        Theta2Minus10Button       matlab.ui.control.Button
        Theta2Minus1Button        matlab.ui.control.Button
        Theta2Add1Button          matlab.ui.control.Button
        Theta2Add10Button         matlab.ui.control.Button
        Theta3Minus10Button       matlab.ui.control.Button
        Theta3Minus1Button        matlab.ui.control.Button
        Theta3Add1Button          matlab.ui.control.Button
        Theta3Add10Button         matlab.ui.control.Button
        XMinus10Button            matlab.ui.control.Button
        XMinus1Button             matlab.ui.control.Button
        XAdd1Button               matlab.ui.control.Button
        XAdd10Button              matlab.ui.control.Button
        YMinus10Button            matlab.ui.control.Button
        YMinus1Button             matlab.ui.control.Button
        YAdd1Button               matlab.ui.control.Button
        YAdd10Button              matlab.ui.control.Button
        ZMinus10Button            matlab.ui.control.Button
        ZMinus1Button             matlab.ui.control.Button
        ZAdd1Button               matlab.ui.control.Button
        ZAdd10Button              matlab.ui.control.Button
        GrabCylinderButton        matlab.ui.control.Button
        GrabCubeButton            matlab.ui.control.Button
        ApproachCylinderButton    matlab.ui.control.Button
        CylinderGrabbedCheckBox   matlab.ui.control.CheckBox
        CubeGrabbedCheckBox       matlab.ui.control.CheckBox
        XSlider_2Label            matlab.ui.control.Label
        XSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        YSlider                   matlab.ui.control.Slider
        ZSliderLabel              matlab.ui.control.Label
        ZSlider                   matlab.ui.control.Slider
        ApproachCubeButton        matlab.ui.control.Button
        RESETPOSITIONButton       matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%
            
            value = app.Theta1EditField.Value;
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
           
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%
            
            value = app.Theta2EditField.Value;

        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.Theta3EditField.Value;

        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % This is the MAIN function.                      %
            % All functions will finally call this function   %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Settings - Do NOT edit, EXCEPT if you    %
            % % need to declare some of them as global %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            clc
            app.NotinworkspaceLamp.Enable = 'off'; % turn off warning lamp
            
            L1 = 0;   % mm, Robot link lengths
            global L2;
            L2 = 150; % mm
            L3 = 100; % mm
            Le = 50;  % mm, end-effector, assume L2 = L3 + Le so that calculation of workspace easier
            
            thetaxRobotWorld = 180.1;           % Transformation between robot and world frames
            thetayRobotWorld = 0.1;             % thetax needs to be around 180 because world frame is flipped
            thetazRobotWorld = 1;               % thetay and thetaz small so that world and robot somewhat aligned apart     
            PRobotWorld = [-100;200;-20];       % from being upside down.
            
            RzWorldObjectBox = 20;              % Transformation between world and box (cube)
                                                % Rotation should only be about z-axis because during calibration, 
                                                % we put checkerboard on object.    
                                                % This is not an assumption but truth.
            PWorldObjectBox = [-50;100;0];      % Box frame wrt. world frame (fixed)
                                                % z-value should be zero because we assumed
                                                % checkerboard (world frame) was on top of
                                                % object.
            PWorldObjectCylinder = [100;50;0];  % Cylinder frame wrt. world frame (fixed)
                                                % z-value should be zero because we assumed
                                                % checkerboard (world frame) was on top of
                                                % object.
                                                
           global PRobotCylinder
           global PRobotBox
           PRobotCylinder = PRobotWorld - [-1;1;1].*PWorldObjectCylinder;
           PRobotBox = PRobotWorld - [-1;1;1].*PWorldObjectBox;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Settings - Leave them as they are at the moment, % 
            % but CHANGE the values AFTER you have done the    %
            % camera & robot calibration workshop              %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            checkerboardScale = 22; % size of checkerboard box in mm - change this
            
            CameraIntrinsics = [867.246733843281,0,0;0,867.854894939250,0;354.066313458159,231.940563190880,1]; 
            RCameraWorld = [0.990761455508743,-0.0111303218791500,0.135158626114148;
0.0242511222098015,0.995102958647013,-0.0958226735356836;
-0.133430211533081,0.0982151598625561,0.986179578486296];
            PCameraWorld = [-125.450253059180,-57.4095634850106,533.366523111273];  % Camera intrinsics (translation) - change this

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Forward Kinematics - CHANGE the  % 
            % matrices T01, T12, T23, T02, T03 %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            q1 = app.Theta1EditField.Value*pi/180;
            q2 = app.Theta2EditField.Value*pi/180;
            q3 = app.Theta3EditField.Value*pi/180;
            
            T01 = [cos(q1), -sin(q1), 0, 0; sin(q1), cos(q1), 0, 0; 0, 0, 1, 0; 0, 0, 0, 1]; % change this
            
            T12 = [cos(q2), -sin(q2), 0, 0; 0, 0, -1, 0; sin(q2), cos(q2), 0, 0; 0, 0, 0, 1]; % change this
             
            
            T23 = [cos(q3), -sin(q3), 0, L2; sin(q3), cos(q3), 0, 0; 0, 0, 1, 0; 0, 0, 0, 1]; % change this
            
            global T02;
            T02 = T01 * T12; % change this  
            T03 = T02 * T23; % change this
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Forward Kinematics - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            P33 = [L3+Le;0;0;1]; % End effector wrt frame 3
            P03 = T03*P33;  % End effector wrt frame 0
           
            app.XEditField.Value = P03(1);
            app.YEditField.Value = P03(2);
            app.ZEditField.Value = P03(3);
            
            app.Theta1Slider.Value = app.Theta1EditField.Value;
            app.Theta2Slider.Value = app.Theta2EditField.Value;
            app.Theta3Slider.Value = app.Theta3EditField.Value;
            
            app.XSlider.Value = app.XEditField.Value;
            app.YSlider.Value = app.YEditField.Value;
            app.ZSlider.Value = app.ZEditField.Value;
            
            x2 = T02(1,4);  % Position of frame 2
            y2 = T02(2,4);
            z2 = T02(3,4);
            
            x3 = T03(1,4);  % Position of frame 3
            y3 = T03(2,4);
            z3 = T03(3,4);
            
            xp = P03(1);    % Position of end-effector
            yp = P03(2);
            zp = P03(3);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of link 2 - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            link2Corners = [0 -25 -25 1; % The 1 in last column is just for homogeneous coordinates
                0 25 -25 1;
                0 25 25 1;
                0 -25 25 1;
                L2 -25 -25 1;
                L2 25 -25 1;
                L2 25 25 1;
                L2 -25 25 1];
            
            link2CornersTransformed = T02*link2Corners';
            link2CornersTransformed =  link2CornersTransformed';
            
            Z2 = [ones(1,5)*mean(link2CornersTransformed(1:4,3));
                link2CornersTransformed(1:4,3)' link2CornersTransformed(1,3);
                link2CornersTransformed(5:8,3)' link2CornersTransformed(5,3);
                ones(1,5)*mean(link2CornersTransformed(5:8,3))];
            X2 = [ones(1,5)*mean(link2CornersTransformed(1:4,1));
                link2CornersTransformed(1:4,1)' link2CornersTransformed(1,1);
                link2CornersTransformed(5:8,1)' link2CornersTransformed(5,1);
                ones(1,5)*mean(link2CornersTransformed(5:8,1))];
            Y2 = [ones(1,5)*mean(link2CornersTransformed(1:4,2));
                link2CornersTransformed(1:4,2)' link2CornersTransformed(1,2);
                link2CornersTransformed(5:8,2)' link2CornersTransformed(5,2);
                ones(1,5)*mean(link2CornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of link 3 - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            link3Corners = [0 -20 -20 1; % The 1 in last column is just for homogeneous coordinates
                0 20 -20 1;
                0 20 20 1;
                0 -20 20 1;
                L3 -20 -20 1;
                L3 20 -20 1;
                L3 20 20 1;
                L3 -20 20 1];
            
            link3CornersTransformed = T03*link3Corners';
            link3CornersTransformed =  link3CornersTransformed';
            
            Z3 = [ones(1,5)*mean(link3CornersTransformed(1:4,3));
                link3CornersTransformed(1:4,3)' link3CornersTransformed(1,3);
                link3CornersTransformed(5:8,3)' link3CornersTransformed(5,3);
                ones(1,5)*mean(link3CornersTransformed(5:8,3))];
            X3 = [ones(1,5)*mean(link3CornersTransformed(1:4,1));
                link3CornersTransformed(1:4,1)' link3CornersTransformed(1,1);
                link3CornersTransformed(5:8,1)' link3CornersTransformed(5,1);
                ones(1,5)*mean(link3CornersTransformed(5:8,1))];
            Y3 = [ones(1,5)*mean(link3CornersTransformed(1:4,2));
                link3CornersTransformed(1:4,2)' link3CornersTransformed(1,2);
                link3CornersTransformed(5:8,2)' link3CornersTransformed(5,2);
                ones(1,5)*mean(link3CornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of left gripper - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            gripperLCorners = [L3 -20 -25 1; % The 1 in last column is just for homogeneous coordinates
                L3 20 -25 1;
                L3 20 -18 1;
                L3 -20 -18 1;
                L3+1.5*Le -20 -25 1;
                L3+1.5*Le 20 -25 1;
                L3+1.5*Le 20 -18 1;
                L3+1.5*Le -20 -18 1];
            
            gripperLCornersTransformed = T03*gripperLCorners';
            gripperLCornersTransformed =  gripperLCornersTransformed';
            
            Zgl = [ones(1,5)*mean(gripperLCornersTransformed(1:4,3));
                gripperLCornersTransformed(1:4,3)' gripperLCornersTransformed(1,3);
                gripperLCornersTransformed(5:8,3)' gripperLCornersTransformed(5,3);
                ones(1,5)*mean(gripperLCornersTransformed(5:8,3))];
            Xgl = [ones(1,5)*mean(gripperLCornersTransformed(1:4,1));
                gripperLCornersTransformed(1:4,1)' gripperLCornersTransformed(1,1);
                gripperLCornersTransformed(5:8,1)' gripperLCornersTransformed(5,1);
                ones(1,5)*mean(gripperLCornersTransformed(5:8,1))];
            Ygl = [ones(1,5)*mean(gripperLCornersTransformed(1:4,2));
                gripperLCornersTransformed(1:4,2)' gripperLCornersTransformed(1,2);
                gripperLCornersTransformed(5:8,2)' gripperLCornersTransformed(5,2);
                ones(1,5)*mean(gripperLCornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of right gripper - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            gripperRCorners = [L3 -20 18 1; % The 1 in last column is just for homogeneous coordinates
                L3 20 18 1;
                L3 20 25 1;
                L3 -20 25 1;
                L3+1.5*Le -20 18 1;
                L3+1.5*Le 20 18 1;
                L3+1.5*Le 20 25 1;
                L3+1.5*Le -20 25 1];
            
            gripperRCornersTransformed = T03*gripperRCorners';
            gripperRCornersTransformed =  gripperRCornersTransformed';
            
            Zgr = [ones(1,5)*mean(gripperRCornersTransformed(1:4,3));
                gripperRCornersTransformed(1:4,3)' gripperRCornersTransformed(1,3);
                gripperRCornersTransformed(5:8,3)' gripperRCornersTransformed(5,3);
                ones(1,5)*mean(gripperRCornersTransformed(5:8,3))];
            Xgr = [ones(1,5)*mean(gripperRCornersTransformed(1:4,1));
                gripperRCornersTransformed(1:4,1)' gripperRCornersTransformed(1,1);
                gripperRCornersTransformed(5:8,1)' gripperRCornersTransformed(5,1);
                ones(1,5)*mean(gripperRCornersTransformed(5:8,1))];
            Ygr = [ones(1,5)*mean(gripperRCornersTransformed(1:4,2));
                gripperRCornersTransformed(1:4,2)' gripperRCornersTransformed(1,2);
                gripperRCornersTransformed(5:8,2)' gripperRCornersTransformed(5,2);
                ones(1,5)*mean(gripperRCornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Calibration Tool - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            CalibToolCorners = [L3 -10 -10 1; % The 1 in last column is just for homogeneous coordinates
                L3 -10 10 1;
                L3 10 10 1;
                L3 10 -10 1
                L3 -10 -10 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1];
            
            CalibToolCornersTransformed = T03*CalibToolCorners';
            CalibToolCornersTransformed =  CalibToolCornersTransformed';
            
            XCalibTool = [CalibToolCornersTransformed(1:5,1)';CalibToolCornersTransformed(6:10,1)'];
            YCalibTool = [CalibToolCornersTransformed(1:5,2)';CalibToolCornersTransformed(6:10,2)'];
            ZCalibTool = [CalibToolCornersTransformed(1:5,3)';CalibToolCornersTransformed(6:10,3)'];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Checkerboard - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            numberBlackBoxesX = 5;      % Checkerboard
            numberBlackBoxesYmin = 3;   % Y has 4 and 3 boxes, use the smaller number
            checkerboardCorners = [];
            for i = 1:numberBlackBoxesX
                for j = 1:numberBlackBoxesYmin
                    checkerboardCorners = [checkerboardCorners;
                        
                        2*i-2 2*j-2;
                        2*i-1 2*j-2;
                        2*i-1 2*j-1;
                        2*i-2 2*j-1;
                        
                        2*i-1 2*j-1;
                        2*i   2*j-1;
                        2*i   2*j;
                        2*i-1 2*j];
                end
            end
            for i = 1:numberBlackBoxesX
                j = numberBlackBoxesYmin+1;
                checkerboardCorners = [checkerboardCorners;
            
                    2*i-2 2*j-2;
                    2*i-1 2*j-2;
                    2*i-1 2*j-1;
                    2*i-2 2*j-1];
            end
            checkerboardCorners(:,1) = checkerboardCorners(:,1)-1; % use these two lines to shift to the correct origin
            checkerboardCorners(:,2) = checkerboardCorners(:,2)-1; 
            checkerboardCorners3D = [checkerboardCorners*checkerboardScale, zeros(length(checkerboardCorners),1), ones(length(checkerboardCorners),1)]; % Homogeneous
            
            RzRobotWorld = [cosd(thetazRobotWorld) -sind(thetazRobotWorld) 0;
                sind(thetazRobotWorld) cosd(thetazRobotWorld) 0;
                0 0 1];
            RyRobotWorld = [cosd(thetayRobotWorld) 0 sind(thetayRobotWorld);
                0 1 0;
                -sind(thetayRobotWorld) 0 cosd(thetayRobotWorld)];
            RxRobotWorld = [1 0 0;
                0 cosd(thetaxRobotWorld) -sind(thetaxRobotWorld);
                0 sind(thetaxRobotWorld) cosd(thetaxRobotWorld)];
            RRobotWorld = RxRobotWorld*RyRobotWorld*RzRobotWorld; 
            TRobotWorld = [RRobotWorld PRobotWorld;0 0 0 1]; % world frame wrt. robot frame (fixed)
            
            checkerboardCorners3DTransformed = TRobotWorld*checkerboardCorners3D';
            checkerboardCorners3DTransformed =  checkerboardCorners3DTransformed';
            
            checkerboardCorners3DPixel = (checkerboardCorners3D(:,1:3)*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            checkerboardCorners3DPixel = checkerboardCorners3DPixel./checkerboardCorners3DPixel(:,3);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % World Frame Arrows on Checkerboard - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            arrowXStart = TRobotWorld*[0;0;0;1];
            arrowXEnd = TRobotWorld*[5*checkerboardScale;0;0;1];
            arrowYStart = TRobotWorld*[0;0;0;1];
            arrowYEnd = TRobotWorld*[0;5*checkerboardScale;0;1];
            arrowZStart = TRobotWorld*[0;0;0;1];
            arrowZEnd = TRobotWorld*[0;0;5*checkerboardScale;1];
            
            arrowXStartPixel = ([0 0 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowXStartPixel = arrowXStartPixel./arrowXStartPixel(:,3);
            arrowXEndPixel = ([5*checkerboardScale 0 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowXEndPixel = arrowXEndPixel./arrowXEndPixel(:,3);
            arrowYStartPixel = ([0 0 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowYStartPixel = arrowYStartPixel./arrowYStartPixel(:,3);
            arrowYEndPixel = ([0 5*checkerboardScale 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowYEndPixel = arrowYEndPixel./arrowYEndPixel(:,3);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cubic Box Object - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ObjectBoxCorners = [-10 -10 20 1; % The 1 in last column is just for homogeneous coordinates
                10 -10 20 1;
                10 10 20 1;
                -10 10 20 1;
                -10 -10 0 1;
                10 -10 0 1;
                10 10 0 1;
                -10 10 0 1];
            
            RWorldObjectBox = [cosd(RzWorldObjectBox) -sind(RzWorldObjectBox) 0;   
                sind(RzWorldObjectBox) cosd(RzWorldObjectBox) 0;                   
                0 0 1];     
            TRobotObjectBox = TRobotWorld*[RWorldObjectBox PWorldObjectBox;0 0 0 1]; % world wrt. robot (fixed) * object wrt. world (fixed) = object wrt. robot (fixed)
            ObjectBoxCornersTransformed = TRobotObjectBox*ObjectBoxCorners';
            ObjectBoxCornersTransformed =  ObjectBoxCornersTransformed';
            
            ZObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,3));
                ObjectBoxCornersTransformed(1:4,3)' ObjectBoxCornersTransformed(1,3);
                ObjectBoxCornersTransformed(5:8,3)' ObjectBoxCornersTransformed(5,3);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,3))];
            XObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,1));
                ObjectBoxCornersTransformed(1:4,1)' ObjectBoxCornersTransformed(1,1);
                ObjectBoxCornersTransformed(5:8,1)' ObjectBoxCornersTransformed(5,1);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,1))];
            YObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,2));
                ObjectBoxCornersTransformed(1:4,2)' ObjectBoxCornersTransformed(1,2);
                ObjectBoxCornersTransformed(5:8,2)' ObjectBoxCornersTransformed(5,2);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,2))];
            
            ObjectBoxCornersPixel = ((ObjectBoxCorners(:,1:3)*RWorldObjectBox' + PWorldObjectBox')*RCameraWorld + PCameraWorld)*CameraIntrinsics;
                    % Checkerboard under transformation between pixel and world frames
                    % RWorldObjectBox needs transpose because (Rx)' = (x' * R') for
                    % row vector
                    % RCameraWorld don't need transpose because it was already for row
                    % vector.
            ObjectBoxCornersPixel = ObjectBoxCornersPixel./ObjectBoxCornersPixel(:,3);
            
            ZObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,3));
                ObjectBoxCornersPixel(1:4,3)' ObjectBoxCornersPixel(1,3);
                ObjectBoxCornersPixel(5:8,3)' ObjectBoxCornersPixel(5,3);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,3))];
            XObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,1));
                ObjectBoxCornersPixel(1:4,1)' ObjectBoxCornersPixel(1,1);
                ObjectBoxCornersPixel(5:8,1)' ObjectBoxCornersPixel(5,1);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,1))];
            YObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,2));
                ObjectBoxCornersPixel(1:4,2)' ObjectBoxCornersPixel(1,2);
                ObjectBoxCornersPixel(5:8,2)' ObjectBoxCornersPixel(5,2);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,2))];
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cubic Box Object Being Grabbed - Add your own code  %
            % to move the cubic object together with end-effector %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
              if app.CubeGrabbedCheckBox.Value
                  ObjectBoxCorners = [-10 -10 20 1; % The 1 in last column is just for homogeneous coordinates
                10 -10 20 1;
                10 10 20 1;
                -10 10 20 1;
                -10 -10 0 1;
                10 -10 0 1;
                10 10 0 1;
                -10 10 0 1];
            NewBoxPos = (P03(1:3).*[1;-1;-1])+(PRobotWorld.*[-1;1;1]); %Set new box pos. = tool frame pos.
            Rx = -[1, 0 ,0; 0 cosd(90) sind(90); 0 -sind(90) cosd(90)];
            Ry = -[cosd(90),0, sind(90); 0,1,0;-sind(90),0,cosd(90)];
            Rz = -[cosd(90) sind(90) 0; -sind(90) cosd(90) 0; 0 0 1];
            RWorldObjectBox = [cosd(RzWorldObjectBox) -sind(RzWorldObjectBox) 0;   
                sind(RzWorldObjectBox) cosd(RzWorldObjectBox) 0;                   
                0 0 1];     
            NewRotBox = T03(1:3,1:3)*Rx*Ry*Rz*RWorldObjectBox.*[-1;1;1];%Set rot. box = rot. tool frame
            TRobotObjectBox = TRobotWorld*[(NewRotBox) NewBoxPos;0 0 0 1]; % world wrt. robot (fixed) * object wrt. world (fixed) = object wrt. robot (fixed)
            ObjectBoxCornersTransformed = TRobotObjectBox*ObjectBoxCorners';
            ObjectBoxCornersTransformed =  ObjectBoxCornersTransformed';
            
            ZObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,3));
                ObjectBoxCornersTransformed(1:4,3)' ObjectBoxCornersTransformed(1,3);
                ObjectBoxCornersTransformed(5:8,3)' ObjectBoxCornersTransformed(5,3);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,3))];
            XObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,1));
                ObjectBoxCornersTransformed(1:4,1)' ObjectBoxCornersTransformed(1,1);
                ObjectBoxCornersTransformed(5:8,1)' ObjectBoxCornersTransformed(5,1);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,1))];
            YObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,2));
                ObjectBoxCornersTransformed(1:4,2)' ObjectBoxCornersTransformed(1,2);
                ObjectBoxCornersTransformed(5:8,2)' ObjectBoxCornersTransformed(5,2);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,2))];
            
            ObjectBoxCornersPixel = ((ObjectBoxCorners(:,1:3)*RWorldObjectBox' + PWorldObjectBox')*RCameraWorld + PCameraWorld)*CameraIntrinsics;
                    % Checkerboard under transformation between pixel and world frames
                    % RWorldObjectBox needs transpose because (Rx)' = (x' * R') for
                    % row vector
                    % RCameraWorld don't need transpose because it was already for row
                    % vector.
            ObjectBoxCornersPixel = ObjectBoxCornersPixel./ObjectBoxCornersPixel(:,3);
            
            ZObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,3));
                ObjectBoxCornersPixel(1:4,3)' ObjectBoxCornersPixel(1,3);
                ObjectBoxCornersPixel(5:8,3)' ObjectBoxCornersPixel(5,3);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,3))];
            XObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,1));
                ObjectBoxCornersPixel(1:4,1)' ObjectBoxCornersPixel(1,1);
                ObjectBoxCornersPixel(5:8,1)' ObjectBoxCornersPixel(5,1);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,1))];
            YObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,2));
                ObjectBoxCornersPixel(1:4,2)' ObjectBoxCornersPixel(1,2);
                ObjectBoxCornersPixel(5:8,2)' ObjectBoxCornersPixel(5,2);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,2))]; 
              end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cylindrical Object - do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [Xcyl,Ycyl,Zcyl] = cylinder(10); % create cylinder with radius r = 10
            hcyl = 20;
            Zcyl = Zcyl*hcyl; % scale height by hcyl
            
            [mcyl,ncyl]=size(Xcyl);
            
            RWorldObjectCylinder= [1 0 0;0 1 0;0 0 1]; % for cylinder, rotation about z doesn't have influence    
            TRobotObjectCylinder = TRobotWorld*[RWorldObjectCylinder PWorldObjectCylinder;0 0 0 1]; % world wrt. robot (fixed) * object wrt. world (fixed) = object wrt. robot (fixed)
            for i=1:mcyl
                for j=1:ncyl
                    XcylTransformed(i,j) = TRobotObjectCylinder(1,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    YcylTransformed(i,j) = TRobotObjectCylinder(2,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    ZcylTransformed(i,j) = TRobotObjectCylinder(3,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    
                    ObjectCylinderPixel(i,j,:) = (([Xcyl(i,j),Ycyl(i,j),Zcyl(i,j)]*RWorldObjectCylinder' + PWorldObjectCylinder')*RCameraWorld + PCameraWorld)*CameraIntrinsics;
                            % Checkerboard under transformation between pixel and world frames
                            % RWorldObjectCylinder needs transpose because (Rx)' = (x' * R') for
                            % row vector
                            % RCameraWorld don't need transpose because it was already for row
                            % vector.
                    ObjectCylinderPixel(i,j,:) = ObjectCylinderPixel(i,j,:)./ObjectCylinderPixel(i,j,3);
                    
                    XcylPixel(i,j) = ObjectCylinderPixel(i,j,1);
                    YcylPixel(i,j) = ObjectCylinderPixel(i,j,2);
                    ZcylPixel(i,j) = ObjectCylinderPixel(i,j,3);
                    
                end
            end
 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cylindrical Object Being Grabbed - Add your own code %
            % to move the cylinder together with end-effector      %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if app.CylinderGrabbedCheckBox.Value
                Rx = -[1, 0 ,0; 0 cosd(90) sind(90); 0 -sind(90) cosd(90)];
                Ry = -[cosd(90),0, sind(90); 0,1,0;-sind(90),0,cosd(90)];
                Rz = -[cosd(90) sind(90) 0; -sind(90) cosd(90) 0; 0 0 1];
%                 Rx = -[1, 0 ,0; 0 cosd(thetaxRobotWorld) sind(thetaxRobotWorld); 0 -sind(thetaxRobotWorld) cosd(thetaxRobotWorld)];
%                 Ry = -[cosd(thetayRobotWorld),0, sind(thetayRobotWorld); 0,1,0;-sind(thetayRobotWorld),0,cosd(thetayRobotWorld)];
%                 Rz = -[cosd(thetazRobotWorld) sind(thetazRobotWorld) 0; -sind(thetazRobotWorld) cosd(thetazRobotWorld) 0; 0 0 1];
                NewCylinderPos = (P03(1:3).*[1;-1;-1])+(PRobotWorld.*[-1;1;1]); %Setting cylinder pos. = tool frame pos.
            NewRotCyl = T03(1:3,1:3)*Rx*Ry*Rz*RWorldObjectCylinder.*[-1;1;1];
            %Not really working
            TRobotObjectCylinder = TRobotWorld*[(NewRotCyl) NewCylinderPos;0 0 0 1]; % world wrt. robot (fixed) * object wrt. world (fixed) = object wrt. robot (fixed)
             for i=1:mcyl
                for j=1:ncyl
                    XcylTransformed(i,j) = TRobotObjectCylinder(1,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    YcylTransformed(i,j) = TRobotObjectCylinder(2,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    ZcylTransformed(i,j) = TRobotObjectCylinder(3,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    
                    ObjectCylinderPixel(i,j,:) = (([Xcyl(i,j),Ycyl(i,j),Zcyl(i,j)]*RWorldObjectCylinder' + PWorldObjectCylinder')*RCameraWorld + PCameraWorld)*CameraIntrinsics;
                            % Checkerboard under transformation between pixel and world frames
                            % RWorldObjectCylinder needs transpose because (Rx)' = (x' * R') for
                            % row vector
                            % RCameraWorld don't need transpose because it was already for row
                            % vector.
                    ObjectCylinderPixel(i,j,:) = ObjectCylinderPixel(i,j,:)./ObjectCylinderPixel(i,j,3);
                    
                    XcylPixel(i,j) = ObjectCylinderPixel(i,j,1);
                    YcylPixel(i,j) = ObjectCylinderPixel(i,j,2);
                    ZcylPixel(i,j) = ObjectCylinderPixel(i,j,3);
                    
                end
             end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plot everything from isometric view - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            plot3(app.UIAxes,[0 x2],[0 y2],[0 z2],'LineWidth',12); % Plot robot link 1
            axis(app.UIAxes,[-(L1+L2+L3+Le)*1.2 (L1+L2+L3+Le)*1.2 -(L1+L2+L3+Le)*1.2 (L1+L2+L3+Le)*1.2 -(L1+L2+L3+Le)*1.2 (L1+L2+L3+Le)*1.2]);
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'y');
            zlabel(app.UIAxes,'z');
            grid(app.UIAxes,'on') 
            AzimuthValue = app.AzimuthSlider.Value;
            ElevationValue = app.ElevationSlider.Value;
            view(app.UIAxes,AzimuthValue,ElevationValue);
            hold(app.UIAxes,'on')
            surf(app.UIAxes,X2,Y2,Z2,'FaceColor','b'); % Plot robot link 2
            surf(app.UIAxes,X3,Y3,Z3,'FaceColor','b'); % Plot robot link 3
            [m,n]=size(Z3);
            surf(app.UIAxes,X2,Y2,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);   % plot robot shadow
            surf(app.UIAxes,X3,Y3,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
            
            plot3(app.UIAxes,xp,yp,zp,'r.','markersize',8); % plot TCP

            quiver3(app.UIAxes,arrowXStart(1),arrowXStart(2),arrowXStart(3),arrowXEnd(1)-arrowXStart(1),arrowXEnd(2)-arrowXStart(2),arrowXEnd(3)-arrowXStart(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','r'); % plot world frame
            quiver3(app.UIAxes,arrowYStart(1),arrowYStart(2),arrowYStart(3),arrowYEnd(1)-arrowYStart(1),arrowYEnd(2)-arrowYStart(2),arrowYEnd(3)-arrowYStart(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','g');
            quiver3(app.UIAxes,arrowZStart(1),arrowZStart(2),arrowZStart(3),arrowZEnd(1)-arrowZStart(1),arrowZEnd(2)-arrowZStart(2),arrowZEnd(3)-arrowZStart(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','b');
            
            if app.ShowGripperCheckBox.Value % plot gripper and shadow
                surf(app.UIAxes,Xgl,Ygl,Zgl,'FaceColor','b');
                surf(app.UIAxes,Xgr,Ygr,Zgr,'FaceColor','b');
                surf(app.UIAxes,Xgl,Ygl,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
                surf(app.UIAxes,Xgr,Ygr,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
            end
            
            if app.ShowCalibToolCheckBox.Value % plot calibration tool and shadow
                [mCalib,nCalib]=size(ZCalibTool);
                surf(app.UIAxes,XCalibTool,YCalibTool,ZCalibTool,'FaceColor','b');
                surf(app.UIAxes,XCalibTool,YCalibTool,ones(mCalib,nCalib)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
            end
            
            if app.ShowCheckerboardCheckBox.Value % plot checkerboard
                fill3(app.UIAxes,checkerboardCorners3DTransformed(1:4,1),checkerboardCorners3DTransformed(1:4,2),checkerboardCorners3DTransformed(1:4,3),'k'); % plot checkerboard
                for k = 2:length(checkerboardCorners)/4
                    fill3(app.UIAxes,checkerboardCorners3DTransformed(4*k-3:4*k,1),checkerboardCorners3DTransformed(4*k-3:4*k,2),checkerboardCorners3DTransformed(4*k-3:4*k,3),'k');
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plot objects - ADD if/else to move object           %
            % together with end-effector, if gripper is activated %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if app.ShowObjectsCheckBox.Value % plot object
                    surf(app.UIAxes,XObjectBox,YObjectBox,ZObjectBox,'FaceColor','c'); % plot cube

                    surf(app.UIAxes,XcylTransformed,YcylTransformed,ZcylTransformed,'FaceColor','y'); % plot cylinder
                    fill3(app.UIAxes,XcylTransformed(1,:),YcylTransformed(1,:),ZcylTransformed(1,:),'y') % plot cylinder faces
                    fill3(app.UIAxes,XcylTransformed(2,:),YcylTransformed(2,:),ZcylTransformed(2,:),'y') % plot cylinder faces
                end
    
            hold(app.UIAxes,'off') % Do NOT edit this line.
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plot object and checkerboard in camera view - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            plot3(app.UIAxes2,arrowXStartPixel(1),arrowXStartPixel(2),arrowXStartPixel(3),'Color','k');   % plot world frame origin
            view(app.UIAxes2,0,90);
            xlabel(app.UIAxes2,'x');
            ylabel(app.UIAxes2,'y');
            zlabel(app.UIAxes2,'z');
            grid(app.UIAxes2,'on') 
            axis(app.UIAxes2,'equal') 
            axis(app.UIAxes2,[0 640 0 480 -100 100]);
            set(app.UIAxes2, 'YDir','reverse')
            
            hold(app.UIAxes2,'on')
            
            if app.ShowCheckerboardCheckBox.Value % plot checkerboard
                fill3(app.UIAxes2,checkerboardCorners3DPixel(1:4,1),checkerboardCorners3DPixel(1:4,2),checkerboardCorners3DPixel(1:4,3),'k'); % plot checkerboard
                for k = 2:length(checkerboardCorners)/4
                    fill3(app.UIAxes2,checkerboardCorners3DPixel(4*k-3:4*k,1),checkerboardCorners3DPixel(4*k-3:4*k,2),checkerboardCorners3DPixel(4*k-3:4*k,3),'k');
                end
            end
            
            if app.ShowObjectsCheckBox.Value % plot object in grey scale
                surf(app.UIAxes2,XObjectBoxPixel,YObjectBoxPixel,ZObjectBoxPixel,'FaceColor',[0 0.5 0],'EdgeColor',[0 0.5 0.5]); % plot cube
                fill3(app.UIAxes2,ObjectBoxCornersPixel(5:8,1),ObjectBoxCornersPixel(5:8,2),ObjectBoxCornersPixel(5:8,3),'c'); % plot top surface
                plot3(app.UIAxes2,[ObjectBoxCornersPixel(5,1),ObjectBoxCornersPixel(7,1)],[ObjectBoxCornersPixel(5,2),ObjectBoxCornersPixel(7,2)],[ObjectBoxCornersPixel(5,3),ObjectBoxCornersPixel(7,3)],'Color','k'); % plot center of top surface
                plot3(app.UIAxes2,[ObjectBoxCornersPixel(6,1),ObjectBoxCornersPixel(8,1)],[ObjectBoxCornersPixel(6,2),ObjectBoxCornersPixel(8,2)],[ObjectBoxCornersPixel(6,3),ObjectBoxCornersPixel(8,3)],'Color','k');
            
                surf(app.UIAxes2,XcylPixel,YcylPixel,ZcylPixel,'FaceColor',[0.5 0.5 0],'EdgeColor',[0.5 0.5 0]); % plot cylinder
                fill3(app.UIAxes2,XcylPixel(1,:),YcylPixel(1,:),ZcylPixel(1,:),'y') % plot top surface
                plot3(app.UIAxes2,mean(XcylPixel(1,:)),mean(YcylPixel(1,:)),mean(ZcylPixel(1,:)),'.','Color','k'); % plot center on top surface
            end
            
            quiver3(app.UIAxes2,arrowXStartPixel(1),arrowXStartPixel(2),arrowXStartPixel(3),arrowXEndPixel(1)-arrowXStartPixel(1),arrowXEndPixel(2)-arrowXStartPixel(2),arrowXEndPixel(3)-arrowXStartPixel(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','r');   % plot world frame
            quiver3(app.UIAxes2,arrowYStartPixel(1),arrowYStartPixel(2),arrowYStartPixel(3),arrowYEndPixel(1)-arrowYStartPixel(1),arrowYEndPixel(2)-arrowYStartPixel(2),arrowYEndPixel(3)-arrowYStartPixel(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','g');   % plot world frame
            
            hold(app.UIAxes2,'off')
           
        end

        % Value changed function: AzimuthSlider
        function AzimuthSliderValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%
            
            value = app.AzimuthSlider.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ElevationSlider
        function ElevationSliderValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ElevationSlider.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowCheckerboardCheckBox
        function ShowCheckerboardCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowCheckerboardCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowObjectsCheckBox
        function ShowObjectsCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowObjectsCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowCalibToolCheckBox
        function ShowCalibToolCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowCalibToolCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowGripperCheckBox
        function ShowGripperCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowGripperCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: PixelXEditField
        function PixelXEditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.PixelXEditField.Value;
            
        end

        % Value changed function: PixelYEditField
        function PixelYEditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%           
            
            value = app.PixelYEditField.Value;
            
        end

        % Value changed function: ViewListBox
        function ViewListBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ViewListBox.Value;           

            ViewListValue = app.ViewListBox.Value;
            
            if(strcmp(ViewListValue,'Default View'))
                set(app.AzimuthSlider, 'Value', -37.5) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 30) 
            elseif(strcmp(ViewListValue,'Front View'))
                set(app.AzimuthSlider, 'Value', 0) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            elseif(strcmp(ViewListValue,'Back View'))
                set(app.AzimuthSlider, 'Value', 180) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            elseif(strcmp(ViewListValue,'Top View'))
                set(app.AzimuthSlider, 'Value', 0) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 90) 
            elseif(strcmp(ViewListValue,'Bottom View'))
                set(app.AzimuthSlider, 'Value', 0) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', -90) 
            elseif(strcmp(ViewListValue,'Right View'))
                set(app.AzimuthSlider, 'Value', 90) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            elseif(strcmp(ViewListValue,'Left View'))
                set(app.AzimuthSlider, 'Value', -90) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            end
            
            ForwardButtonPushed(app, event)
            
        end

        % Button pushed function: SnapCameraButton
        function SnapCameraButtonPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%           
            
            fignew = figure % Create new figure
            axNew = axes; % Create new axes
            
            copyobj(app.UIAxes2.Children, axNew) % Copy all objects from UIAxes to new axis
            
            uiAxParams = get(app.UIAxes2); % Save all parameters of the UIAxes
            uiAxParamNames = fieldnames(uiAxParams); 
            
            editableParams = fieldnames(set(axNew)); % Get list of editable params in new axis
            
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition']; 
            uiAxGoodParams = rmfield(uiAxParams,badFields); 
            
            set(axNew, uiAxGoodParams) % set editable params on new axes
            
            savefig(fignew,'CameraView.fig');
            delete(fignew);
            
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)

            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            
            app.XSlider.Value = app.XEditField.Value;
            app.YSlider.Value = app.YEditField.Value;
            app.ZSlider.Value = app.ZEditField.Value;
            
            global L2;
            flag = 0;
            
            if sqrt(x^2+y^2+z^2)<= 2*L2
                flag = 1;
            end
            
            if flag == 1
                % For theta 1
                app.Theta1EditField.Value = atan2(y,x)*180/pi;
           
            
                % For theta 3
                C3 = (x^2+y^2+z^2)/(2*L2^2)-1;
                S3 = sqrt(1-C3^2);
               
                theta3_positive = atan2 (S3,C3) *180 /pi;
                theta3_negative = atan2 (-S3,C3) *180 /pi;
                
                if (theta3_negative < theta3_positive  && app.Theta3EditField.Value <= 0)
                    app.Theta3EditField.Value = theta3_negative;
                    S3 = -S3;
                else
                    app.Theta3EditField.Value = theta3_positive;
                end
            
                % For theta 2
                
                k1 = L2*(1+C3);
                k2 = L2*S3;
            
                r = sqrt(k1^2+k2^2);
                beta = atan2(k2,k1);
            
                app.Theta2EditField.Value = (atan2(z/r,sqrt(x^2+y^2)/r)-beta) *180/pi;
                app.Theta1Slider.Value = app.Theta1EditField.Value;
                app.Theta2Slider.Value = app.Theta2EditField.Value;
                app.Theta3Slider.Value = app.Theta3EditField.Value;
                ForwardButtonPushed(app,event)
            
            else
            f = msgbox("The target cannot be reached","Error","error");
            end
        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta1EditField.Value = changingValue;     
            ForwardButtonPushed(app,event)         
        end

        % Button pushed function: FWDTrajectoryButton
        function FWDTrajectoryButtonPushed(app, event)

             %% TRAJECTORY PLANNING 
            
            % Boundary Conditions
            u0_1 = app.Theta1Slider.Value;
            u0_2 = app.Theta2Slider.Value;
            u0_3 = app.Theta3Slider.Value;
            
            uf_1 = app.Theta1EditField.Value;
            uf_2 = app.Theta2EditField.Value;
            uf_3 = app.Theta3EditField.Value;
            
            tfirst = 2;
            trest = 2;
            tfinal = 2;
            
            %Create Time Array 
            time = 0.1;
            t = 0:time:tfirst;
            t = t';
            
            %Cubic Polynomial for Theta 1
 
            a0 = [u0_1, u0_2, u0_3];
            a1 = [0, 0, 0];
            a2 = [(3/tfirst^2)*(uf_1-u0_1), (3/tfirst^2)*(uf_2-u0_2), (3/tfirst^2)*(uf_3-u0_3)];
            a3 = [(-2/tfirst^3)*(uf_1-u0_1), (-2/tfirst^3)*(uf_2-u0_2), (-2/tfirst^3)*(uf_3-u0_3)];
            
            uCubic = [a0(1)*ones(length(t),1)+a1(1)*t+a2(1)*t.^2+a3(1)*t.^3,...
                        a0(2)*ones(length(t),1)+a1(2)*t+a2(2)*t.^2+a3(2)*t.^3,...
                            a0(3)*ones(length(t),1)+a1(3)*t+a2(3)*t.^2+a3(3)*t.^3];
            
                assignin("base","uCubic",[a0(1)*ones(length(t),1)+a1(1)*t+a2(1)*t.^2+a3(1)*t.^3,...
                        a0(2)*ones(length(t),1)+a1(2)*t+a2(2)*t.^2+a3(2)*t.^3,...
                            a0(3)*ones(length(t),1)+a1(3)*t+a2(3)*t.^2+a3(3)*t.^3])
            
            uDotCubic = [a1(1)*ones(length(t),1)+2*a2(1)*3*a3(1)*t.^2,...
                            a1(2)*ones(length(t),1)+2*a2(2)*3*a3(2)*t.^2,...
                                a1(3)*ones(length(t),1)+2*a2(2)*3*a3(3)*t.^2];
            
            uDoubleDotCubic = [2*a2(1)*ones(length(t),1)+6*a3(1)*t,...
                                 2*a2(2)*ones(length(t),1)+6*a3(2)*t,...
                                    2*a2(3)*ones(length(t),1)+6*a3(3)*t];
            
            %%%
            i = 0;
            for i = 1:numel(t)
                app.Theta1EditField.Value = uCubic(i,1);
                app.Theta1Slider.Value = uCubic(i,1);
                
                app.Theta2EditField.Value = uCubic(i,2);
                app.Theta2Slider.Value = uCubic(i,2);

                app.Theta3EditField.Value = uCubic(i,3);
                app.Theta3Slider.Value = uCubic(i,3);
                ForwardButtonPushed(app,event)
                pause(time)
            end
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta3EditField.Value = changingValue;     
            ForwardButtonPushed(app,event)  
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Theta2EditField.Value = changingValue;     
            ForwardButtonPushed(app,event)  
        end

        % Button pushed function: InverseTrajectoryButton
        function InverseTrajectoryButtonPushed(app, event)
            x = app.XEditField.Value;
            y = app.YEditField.Value;
            z = app.ZEditField.Value;
            
            global L2;
            flag = 0;
            
            if sqrt(x^2+y^2+z^2)<= 2*L2
                flag = 1;
            end
            
            if flag == 1
                % For theta 1
                Theta1_final = atan2(y,x)*180/pi;
           
            
                % For theta 3
                C3 = (x^2+y^2+z^2)/(2*L2^2)-1;
                S3 = sqrt(1-C3^2);
                theta3_positive = atan2 (S3,C3) *180 /pi;
                theta3_negative = atan2 (-S3,C3) *180 /pi;
                
                if (theta3_negative < theta3_positive && app.Theta3EditField.Value <= 0)
                    Theta3_final = theta3_negative;
                    S3 = -S3;
                else
                    Theta3_final = theta3_positive;
                end
                % For theta 2
            
                k1 = L2*(1+C3);
                k2 = L2*S3;
            
                r = sqrt(k1^2+k2^2);
                beta = atan2(k2,k1);
            
                Theta2_final_pos = (atan2(z/r,sqrt(x^2+y^2)/r)-beta) *180/pi;
                Theta2_final_neg = (atan2(z/r,-sqrt(x^2+y^2)/r)-beta) *180/pi;
            
            else
            f = msgbox("The target cannot be reached","Error","error");
            end
            
            u0_1 = app.Theta1EditField.Value; 
            u0_2 = app.Theta2EditField.Value;
            u0_3 = app.Theta3EditField.Value;

            uf_1 = Theta1_final;
            uf_2 = Theta2_final_pos;
            uf_3 = Theta3_final;
            
            tfirst = 2;
            trest = 2;
            tfinal = 2;
            
            %Create Time Array 
            time = 0.2;
            t = 0:time:tfirst;
            t = t';
            
            %Cubic Polynomial for Theta 1
 
            a0 = [u0_1, u0_2, u0_3];
            a1 = [0, 0, 0];
            a2 = [(3/tfirst^2)*(uf_1-u0_1), (3/tfirst^2)*(uf_2-u0_2), (3/tfirst^2)*(uf_3-u0_3)];
            a3 = [(-2/tfirst^3)*(uf_1-u0_1), (-2/tfirst^3)*(uf_2-u0_2), (-2/tfirst^3)*(uf_3-u0_3)];
            
            uCubic = [a0(1)*ones(length(t),1)+a1(1)*t+a2(1)*t.^2+a3(1)*t.^3,...
                        a0(2)*ones(length(t),1)+a1(2)*t+a2(2)*t.^2+a3(2)*t.^3,...
                            a0(3)*ones(length(t),1)+a1(3)*t+a2(3)*t.^2+a3(3)*t.^3];
            
            
            uCubic_d1 = [a1(1)*ones(length(t),1)+2*a2(1)*3*a3(1)*t.^2,...
                            a1(2)*ones(length(t),1)+2*a2(2)*3*a3(2)*t.^2,...
                                a1(3)*ones(length(t),1)+2*a2(2)*3*a3(3)*t.^2];
            
            uCubic_d2 = [2*a2(1)*ones(length(t),1)+6*a3(1)*t,...
                                 2*a2(2)*ones(length(t),1)+6*a3(2)*t,...
                                    2*a2(3)*ones(length(t),1)+6*a3(3)*t];
            
            %%%
            for i = 1:numel(t)
                app.Theta1EditField.Value = uCubic(i,1);
                app.Theta1Slider.Value = uCubic(i,1);
             
                
                app.Theta2EditField.Value = uCubic(i,2);
                app.Theta2Slider.Value = uCubic(i,2);

                
                app.Theta3EditField.Value = uCubic(i,3);
                app.Theta3Slider.Value = uCubic(i,3);
                ForwardButtonPushed(app,event)
                pause(time)
            end
        end

        % Button pushed function: Theta1Minus10Button
        function Theta1Minus10ButtonPushed(app, event)
            app.Theta1EditField.Value = app.Theta1EditField.Value - 10;
            app.Theta1Slider.Value = app.Theta1Slider.Value - 10;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta1Minus1Button
        function Theta1Minus1ButtonPushed(app, event)
            app.Theta1EditField.Value = app.Theta1EditField.Value - 1;
            app.Theta1Slider.Value = app.Theta1Slider.Value - 1;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta1Add1Button
        function Theta1Add1ButtonPushed(app, event)
            app.Theta1EditField.Value = app.Theta1EditField.Value + 1;
            app.Theta1Slider.Value = app.Theta1Slider.Value + 1;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta1Add10Button
        function Theta1Add10ButtonPushed(app, event)
            app.Theta1EditField.Value = app.Theta1EditField.Value + 10;
            app.Theta1Slider.Value = app.Theta1Slider.Value + 10;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta2Minus10Button
        function Theta2Minus10ButtonPushed(app, event)
            app.Theta2EditField.Value = app.Theta2EditField.Value - 10;
            app.Theta2Slider.Value = app.Theta2Slider.Value - 10;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta2Minus1Button
        function Theta2Minus1ButtonPushed(app, event)
            app.Theta2EditField.Value = app.Theta2EditField.Value - 1;
            app.Theta2Slider.Value = app.Theta2Slider.Value - 1;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta2Add1Button
        function Theta2Add1ButtonPushed(app, event)
            app.Theta2EditField.Value = app.Theta2EditField.Value + 1;
            app.Theta2Slider.Value = app.Theta2Slider.Value + 1;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta2Add10Button
        function Theta2Add10ButtonPushed(app, event)
            app.Theta2EditField.Value = app.Theta2EditField.Value + 10;
            app.Theta2Slider.Value = app.Theta2Slider.Value + 10;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta3Minus10Button
        function Theta3Minus10ButtonPushed(app, event)
            app.Theta3EditField.Value = app.Theta3EditField.Value - 10;
            app.Theta3Slider.Value = app.Theta3Slider.Value - 10;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta3Minus1Button
        function Theta3Minus1ButtonPushed(app, event)
            app.Theta3EditField.Value = app.Theta3EditField.Value - 1;
            app.Theta3Slider.Value = app.Theta3Slider.Value - 1;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta3Add1Button
        function Theta3Add1ButtonPushed(app, event)
            app.Theta3EditField.Value = app.Theta3EditField.Value + 1;
            app.Theta3Slider.Value = app.Theta3Slider.Value + 1;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: Theta3Add10Button
        function Theta3Add10ButtonPushed(app, event)
            app.Theta3EditField.Value = app.Theta3EditField.Value + 10;
            app.Theta3Slider.Value = app.Theta3Slider.Value + 10;
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: XMinus10Button
        function XMinus10ButtonPushed(app, event)
            app.XEditField.Value = app.XEditField.Value - 10;
            app.XSlider.Value = app.XSlider.Value - 10;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: XMinus1Button
        function XMinus1ButtonPushed(app, event)
            app.XEditField.Value = app.XEditField.Value - 1;
            app.XSlider.Value = app.XSlider.Value - 1;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: XAdd1Button
        function XAdd1ButtonPushed(app, event)
            app.XEditField.Value = app.XEditField.Value + 1;
            app.XSlider.Value = app.XSlider.Value + 10;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: XAdd10Button
        function XAdd10ButtonPushed(app, event)
            app.XEditField.Value = app.XEditField.Value + 10;
            app.XSlider.Value = app.XSlider.Value + 10;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: YMinus10Button
        function YMinus10ButtonPushed(app, event)
            app.YEditField.Value = app.YEditField.Value - 10;
            app.YSlider.Value = app.YSlider.Value - 10;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: YMinus1Button
        function YMinus1ButtonPushed(app, event)
            app.YEditField.Value = app.YEditField.Value - 1;
            app.YSlider.Value = app.YSlider.Value - 1;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: YAdd1Button
        function YAdd1ButtonPushed(app, event)
            app.YEditField.Value = app.YEditField.Value + 1;
            app.YSlider.Value = app.YSlider.Value + 1;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: YAdd10Button
        function YAdd10ButtonPushed(app, event)
            app.YEditField.Value = app.YEditField.Value + 10;
            app.YSlider.Value = app.YSlider.Value + 10;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: ZMinus10Button
        function ZMinus10ButtonPushed(app, event)
            app.ZEditField.Value = app.ZEditField.Value - 10;
            app.ZSlider.Value = app.ZSlider.Value - 10;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: ZMinus1Button
        function ZMinus1ButtonPushed(app, event)
            app.ZEditField.Value = app.ZEditField.Value - 1;
            app.ZSlider.Value = app.ZSlider.Value - 1;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: ZAdd1Button
        function ZAdd1ButtonPushed(app, event)
            app.ZEditField.Value = app.ZEditField.Value + 1;
            app.ZSlider.Value = app.ZSlider.Value + 1;
            InverseButtonPushed(app,event)
        end

        % Button pushed function: ZAdd10Button
        function ZAdd10ButtonPushed(app, event)
            app.ZEditField.Value = app.ZEditField.Value + 10;
            app.ZSlider.Value = app.ZSlider.Value + 10;
            InverseButtonPushed(app,event)
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.XEditField.Value = changingValue;     
            InverseButtonPushed(app,event)   
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.YEditField.Value = changingValue;     
            InverseButtonPushed(app,event)  
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ZEditField.Value = changingValue;     
            InverseButtonPushed(app,event)  
        end

        % Button pushed function: ApproachCylinderButton
        function ApproachCylinderButtonPushed(app, event)
            global PRobotCylinder
            app.XEditField.Value = PRobotCylinder(1);
            app.YEditField.Value = PRobotCylinder(2);
            app.ZEditField.Value = PRobotCylinder(3) + 40;
            InverseTrajectoryButtonPushed(app,event);
            pause(2)
            app.ZEditField.Value = PRobotCylinder(3);
            InverseTrajectoryButtonPushed(app,event);
        end

        % Button pushed function: GrabCylinderButton
        function GrabCylinderButtonPushed(app, event)

            if app.CylinderGrabbedCheckBox.Value == 0
                app.CylinderGrabbedCheckBox.Value = 1;
            else
                app.CylinderGrabbedCheckBox.Value = 0;
                ForwardButtonPushed(app,event);
            end
        end

        % Button pushed function: ApproachCubeButton
        function ApproachCubeButtonPushed(app, event)
            global PRobotBox
            app.XEditField.Value = PRobotBox(1);
            app.YEditField.Value = PRobotBox(2);
            app.ZEditField.Value = PRobotBox(3)+40;
            InverseTrajectoryButtonPushed(app,event);
            pause(2)
            app.ZEditField.Value = PRobotBox(3);
            InverseTrajectoryButtonPushed(app,event);
        end

        % Button pushed function: GrabCubeButton
        function GrabCubeButtonPushed(app, event)
            if app.CubeGrabbedCheckBox.Value == 0
                app.CubeGrabbedCheckBox.Value = 1;
            else
                app.CubeGrabbedCheckBox.Value = 0;
                ForwardButtonPushed(app,event);
            end
        end

        % Button pushed function: RESETPOSITIONButton
        function RESETPOSITIONButtonPushed(app, event)
            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0;
            app.Theta3EditField.Value = 0;
            ForwardButtonPushed(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueDisplayFormat = '%.2f';
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueDisplayFormat = '%.2f';
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueDisplayFormat = '%.2f';
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.AzimuthSliderLabel = uilabel(app.UIFigure);
            app.AzimuthSliderLabel.Text = 'Azimuth';
            app.AzimuthSlider = uislider(app.UIFigure);
            app.AzimuthSlider.Limits = [-180 180];
            app.AzimuthSlider.MajorTicks = [-180 -90 0 90 180];
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @AzimuthSliderValueChanged, true);
            app.AzimuthSlider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.AzimuthSlider.Value = -37.5;
            app.ElevationSliderLabel = uilabel(app.UIFigure);
            app.ElevationSliderLabel.Text = 'Elevation';
            app.ElevationSlider = uislider(app.UIFigure);
            app.ElevationSlider.Limits = [-180 180];
            app.ElevationSlider.MajorTicks = [-180 -90 0 90 180];
            app.ElevationSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderValueChanged, true);
            app.ElevationSlider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.ElevationSlider.Value = 30;
            app.ShowCheckerboardCheckBox = uicheckbox(app.UIFigure);
            app.ShowCheckerboardCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCheckerboardCheckBoxValueChanged, true);
            app.ShowCheckerboardCheckBox.Text = 'Show Checkerboard';
            app.ShowCheckerboardCheckBox.Value = true;
            app.ShowObjectsCheckBox = uicheckbox(app.UIFigure);
            app.ShowObjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowObjectsCheckBoxValueChanged, true);
            app.ShowObjectsCheckBox.Text = 'Show Objects';
            app.ShowObjectsCheckBox.Value = true;
            app.ShowCalibToolCheckBox = uicheckbox(app.UIFigure);
            app.ShowCalibToolCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCalibToolCheckBoxValueChanged, true);
            app.ShowCalibToolCheckBox.Text = 'Show Calib Tool';
            app.ShowGripperCheckBox = uicheckbox(app.UIFigure);
            app.ShowGripperCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGripperCheckBoxValueChanged, true);
            app.ShowGripperCheckBox.Text = 'Show Gripper';
            app.ShowGripperCheckBox.Value = true;
            app.PixelXEditFieldLabel = uilabel(app.UIFigure);
            app.PixelXEditFieldLabel.Text = 'Pixel X';
            app.PixelXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelXEditField.ValueDisplayFormat = '%.0f';
            app.PixelXEditField.ValueChangedFcn = createCallbackFcn(app, @PixelXEditFieldValueChanged, true);
            app.PixelYEditFieldLabel = uilabel(app.UIFigure);
            app.PixelYEditFieldLabel.Text = 'Pixel Y';
            app.PixelYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelYEditField.ValueDisplayFormat = '%.0f';
            app.PixelYEditField.ValueChangedFcn = createCallbackFcn(app, @PixelYEditFieldValueChanged, true);
            app.ViewListBoxLabel = uilabel(app.UIFigure);
            app.ViewListBoxLabel.Text = 'View';
            app.ViewListBox = uilistbox(app.UIFigure);
            app.ViewListBox.Items = {'Default View', 'Top View', 'Bottom View', 'Front View', 'Back View', 'Right View', 'Left View'};
            app.ViewListBox.ValueChangedFcn = createCallbackFcn(app, @ViewListBoxValueChanged, true);
            app.ViewListBox.Value = 'Default View';
            app.SnapCameraButton = uibutton(app.UIFigure, 'push');
            app.SnapCameraButton.ButtonPushedFcn = createCallbackFcn(app, @SnapCameraButtonPushed, true);
            app.SnapCameraButton.Text = 'Snap Camera';
            app.NotinworkspaceLabel = uilabel(app.UIFigure);
            app.NotinworkspaceLabel.Text = {'Not in '; 'workspace'};
            app.NotinworkspaceLamp = uilamp(app.UIFigure);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.Theta1Slider = uislider(app.UIFigure);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta2';
            app.Theta2Slider = uislider(app.UIFigure);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta3';
            app.Theta3Slider = uislider(app.UIFigure);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.FWDTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.FWDTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @FWDTrajectoryButtonPushed, true);
            app.FWDTrajectoryButton.Text = 'FWD Trajectory';
            app.InverseTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.InverseTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @InverseTrajectoryButtonPushed, true);
            app.InverseTrajectoryButton.Text = 'Inverse Trajectory';
            app.Theta1Minus10Button = uibutton(app.UIFigure, 'push');
            app.Theta1Minus10Button.ButtonPushedFcn = createCallbackFcn(app, @Theta1Minus10ButtonPushed, true);
            app.Theta1Minus10Button.Text = '-10';
            app.Theta1Minus1Button = uibutton(app.UIFigure, 'push');
            app.Theta1Minus1Button.ButtonPushedFcn = createCallbackFcn(app, @Theta1Minus1ButtonPushed, true);
            app.Theta1Minus1Button.Text = '-1';
            app.Theta1Add1Button = uibutton(app.UIFigure, 'push');
            app.Theta1Add1Button.ButtonPushedFcn = createCallbackFcn(app, @Theta1Add1ButtonPushed, true);
            app.Theta1Add1Button.Text = '+1';
            app.Theta1Add10Button = uibutton(app.UIFigure, 'push');
            app.Theta1Add10Button.ButtonPushedFcn = createCallbackFcn(app, @Theta1Add10ButtonPushed, true);
            app.Theta1Add10Button.Text = '+10';
            app.Theta2Minus10Button = uibutton(app.UIFigure, 'push');
            app.Theta2Minus10Button.ButtonPushedFcn = createCallbackFcn(app, @Theta2Minus10ButtonPushed, true);
            app.Theta2Minus10Button.Text = '-10';
            app.Theta2Minus1Button = uibutton(app.UIFigure, 'push');
            app.Theta2Minus1Button.ButtonPushedFcn = createCallbackFcn(app, @Theta2Minus1ButtonPushed, true);
            app.Theta2Minus1Button.Text = '-1';
            app.Theta2Add1Button = uibutton(app.UIFigure, 'push');
            app.Theta2Add1Button.ButtonPushedFcn = createCallbackFcn(app, @Theta2Add1ButtonPushed, true);
            app.Theta2Add1Button.Text = '+1';
            app.Theta2Add10Button = uibutton(app.UIFigure, 'push');
            app.Theta2Add10Button.ButtonPushedFcn = createCallbackFcn(app, @Theta2Add10ButtonPushed, true);
            app.Theta2Add10Button.Text = '+10';
            app.Theta3Minus10Button = uibutton(app.UIFigure, 'push');
            app.Theta3Minus10Button.ButtonPushedFcn = createCallbackFcn(app, @Theta3Minus10ButtonPushed, true);
            app.Theta3Minus10Button.Text = '-10';
            app.Theta3Minus1Button = uibutton(app.UIFigure, 'push');
            app.Theta3Minus1Button.ButtonPushedFcn = createCallbackFcn(app, @Theta3Minus1ButtonPushed, true);
            app.Theta3Minus1Button.Text = '-1';
            app.Theta3Add1Button = uibutton(app.UIFigure, 'push');
            app.Theta3Add1Button.ButtonPushedFcn = createCallbackFcn(app, @Theta3Add1ButtonPushed, true);
            app.Theta3Add1Button.Text = '+1';
            app.Theta3Add10Button = uibutton(app.UIFigure, 'push');
            app.Theta3Add10Button.ButtonPushedFcn = createCallbackFcn(app, @Theta3Add10ButtonPushed, true);
            app.Theta3Add10Button.Text = '+10';
            app.XMinus10Button = uibutton(app.UIFigure, 'push');
            app.XMinus10Button.ButtonPushedFcn = createCallbackFcn(app, @XMinus10ButtonPushed, true);
            app.XMinus10Button.Text = '-10';
            app.XMinus1Button = uibutton(app.UIFigure, 'push');
            app.XMinus1Button.ButtonPushedFcn = createCallbackFcn(app, @XMinus1ButtonPushed, true);
            app.XMinus1Button.Text = '-1';
            app.XAdd1Button = uibutton(app.UIFigure, 'push');
            app.XAdd1Button.ButtonPushedFcn = createCallbackFcn(app, @XAdd1ButtonPushed, true);
            app.XAdd1Button.Text = '+1';
            app.XAdd10Button = uibutton(app.UIFigure, 'push');
            app.XAdd10Button.ButtonPushedFcn = createCallbackFcn(app, @XAdd10ButtonPushed, true);
            app.XAdd10Button.Text = '+10';
            app.YMinus10Button = uibutton(app.UIFigure, 'push');
            app.YMinus10Button.ButtonPushedFcn = createCallbackFcn(app, @YMinus10ButtonPushed, true);
            app.YMinus10Button.Text = '-10';
            app.YMinus1Button = uibutton(app.UIFigure, 'push');
            app.YMinus1Button.ButtonPushedFcn = createCallbackFcn(app, @YMinus1ButtonPushed, true);
            app.YMinus1Button.Text = '-1';
            app.YAdd1Button = uibutton(app.UIFigure, 'push');
            app.YAdd1Button.ButtonPushedFcn = createCallbackFcn(app, @YAdd1ButtonPushed, true);
            app.YAdd1Button.Text = '+1';
            app.YAdd10Button = uibutton(app.UIFigure, 'push');
            app.YAdd10Button.ButtonPushedFcn = createCallbackFcn(app, @YAdd10ButtonPushed, true);
            app.YAdd10Button.Text = '+10';
            app.ZMinus10Button = uibutton(app.UIFigure, 'push');
            app.ZMinus10Button.ButtonPushedFcn = createCallbackFcn(app, @ZMinus10ButtonPushed, true);
            app.ZMinus10Button.Text = '-10';
            app.ZMinus1Button = uibutton(app.UIFigure, 'push');
            app.ZMinus1Button.ButtonPushedFcn = createCallbackFcn(app, @ZMinus1ButtonPushed, true);
            app.ZMinus1Button.Text = '-1';
            app.ZAdd1Button = uibutton(app.UIFigure, 'push');
            app.ZAdd1Button.ButtonPushedFcn = createCallbackFcn(app, @ZAdd1ButtonPushed, true);
            app.ZAdd1Button.Text = '+1';
            app.ZAdd10Button = uibutton(app.UIFigure, 'push');
            app.ZAdd10Button.ButtonPushedFcn = createCallbackFcn(app, @ZAdd10ButtonPushed, true);
            app.ZAdd10Button.Text = '+10';
            app.GrabCylinderButton = uibutton(app.UIFigure, 'push');
            app.GrabCylinderButton.ButtonPushedFcn = createCallbackFcn(app, @GrabCylinderButtonPushed, true);
            app.GrabCylinderButton.Text = 'Grab Cylinder';
            app.GrabCubeButton = uibutton(app.UIFigure, 'push');
            app.GrabCubeButton.ButtonPushedFcn = createCallbackFcn(app, @GrabCubeButtonPushed, true);
            app.GrabCubeButton.Text = 'Grab Cube';
            app.ApproachCylinderButton = uibutton(app.UIFigure, 'push');
            app.ApproachCylinderButton.ButtonPushedFcn = createCallbackFcn(app, @ApproachCylinderButtonPushed, true);
            app.ApproachCylinderButton.Text = 'Approach Cylinder';
            app.CylinderGrabbedCheckBox = uicheckbox(app.UIFigure);
            app.CylinderGrabbedCheckBox.Text = 'Cylinder Grabbed?';
            app.CubeGrabbedCheckBox = uicheckbox(app.UIFigure);
            app.CubeGrabbedCheckBox.Text = 'Cube Grabbed?';
            app.XSlider_2Label = uilabel(app.UIFigure);
            app.XSlider_2Label.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-300 300];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-300 300];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-300 300];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ApproachCubeButton = uibutton(app.UIFigure, 'push');
            app.ApproachCubeButton.ButtonPushedFcn = createCallbackFcn(app, @ApproachCubeButtonPushed, true);
            app.ApproachCubeButton.Text = 'Approach Cube';
            app.RESETPOSITIONButton = uibutton(app.UIFigure, 'push');
            app.RESETPOSITIONButton.ButtonPushedFcn = createCallbackFcn(app, @RESETPOSITIONButtonPushed, true);
            app.RESETPOSITIONButton.Text = 'RESET POSITION';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'User View')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Camera View (Snapshot Before Pickup)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VirtualRobot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end